classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        DES                         matlab.ui.control.Label
        DEC                         matlab.ui.control.Label
        MS                          matlab.ui.control.Label
        MC                          matlab.ui.control.Label
        Indicedemasacorporal2Presinarterial3mediaColesterolLabel  matlab.ui.control.Label
        QuequieresobservarDropDown  matlab.ui.control.DropDown
        QuequieresobservarDropDownLabel  matlab.ui.control.Label
        info                        matlab.ui.control.Label
        t2                          matlab.ui.control.Label
        t1                          matlab.ui.control.Label
        x2                          matlab.ui.control.Label
        y2                          matlab.ui.control.Label
        y1                          matlab.ui.control.Label
        x1                          matlab.ui.control.Label
        PuntoLabel                  matlab.ui.control.Label
        Peso                        matlab.ui.control.Label
        Ejer                        matlab.ui.control.Label
        Alco                        matlab.ui.control.Label
        Fum                         matlab.ui.control.Label
        Procar                      matlab.ui.control.Label
        DLabel                      matlab.ui.control.Label
        NUM                         matlab.ui.control.NumericEditField
        EscribeelIDdelparticipanteEditFieldLabel  matlab.ui.control.Label
        Boton                       matlab.ui.control.Button
        Lista56                     matlab.ui.control.DropDown
        QuequieresgraficarLabel     matlab.ui.control.Label
        Grafica2                    matlab.ui.control.UIAxes
        Grafica1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        heartdata % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Punto 3.a
load Heart.mat
Edad=heart_data.age;
Edad=Edad/365;
%% Punto 3.B
AF=heart_data.active;%Se extrae unicamente la parte de la tabla que indica actividad fis
T=size(Edad,1);%En T meto la varible de el numero de filas
Af=strings(T,1);%Creo una matriz de 70000 sin datos para poder escribir si Hace ejercicio o no
Sm=strings(T,1);%Creo una matriz de 70000 sin datos para poder escribir si fuma o no
for i= 1:T %Un for para ir de la primera fila a la 70000 y asi poder preguntar si cada persona hace ejercico o no
    if heart_data.active(i) ==0 
        Af(i)="No hace ejercicio"; 
    else 
        Af(i)="Hace ejercicio";
    end
end
heart_data.Haceejercicio=Af; %Se crea una nueva columna y se coloca si hace o no
for i= 1:T
    if heart_data.smoke(i) ==0
        Sm(i)="No fuma";
    else 
        Sm(i)="Si fuma";
    end
end
heart_data.Fuma=Sm; %Se repite pero en este caso si fuma o no
%% Punto 3.c
Altura=heart_data.height;
Peso=heart_data.weight;
Altura=Altura/100;
Altura=Altura.*Altura;
imc=Peso./Altura;
Imc=strings(T,1);
for i= 1:T
    if imc(i)<18.5
        Imc(i)="Esta bajo de peso";
    elseif 18.5<=imc(i) && imc(i)<=25
        Imc(i)="Tiene un peso adecuado";
    else 
        Imc(i)="Tiene sobrepeso";
    end
end
heart_data.IMC=Imc;
%% Punto 3.d
Sis=heart_data.ap_hi;
Dias=heart_data.ap_lo;
Ap=Sis-Dias/3;
Ap=Ap+Dias;
heart_data.presionarterialmedia=Ap;
        end

        % Button pushed function: Boton
        function BotonButtonPushed(app, event)
            
            load Heart.mat
            PC=heart_data.cardio;
            Altura=heart_data.height;
            Peso=heart_data.weight;
            Altura=Altura/100;
            Altura=Altura.*Altura;
            imc=Peso./Altura;
           
            %% todo esto es lo necesario para que se ejecute el codigo (globalizarlo)
             value = app.NUM.Value;
            app.DLabel.Text= " El participante numero "+num2str(value) +" Tiene las siguientes caracteristicas:" ;

            if PC(value)==0
                app.Procar.Text= ("-No tiene problemas cardiacos");
            else
                app.Procar.Text= ("-Tiene problemas cardiacos");
            end

            if heart_data.smoke(value) ==0
                 app.Fum.Text= ("-No fuma");
            else 
                 app.Fum.Text= ("-Si fuma");
            end
            if heart_data.alco(value) ==0
                  app.Alco.Text= ("-No Toma alcohol");
            else 
                  app.Alco.Text= ("-Si Toma alcohol");
            end
            if heart_data.active(value) ==0
                  app.Ejer.Text= ("-No hace ejercicio");
            else 
                  app.Ejer.Text= ("-Si hace ejercicio");
            end
            if imc(value)<=18.5
                  app.Peso.Text=("Esta bajo de peso");
            elseif 18.5<=imc(value) && imc(value)<=25
                  app.Peso.Text=("Tiene un peso adecuado");
            else 
                  app.Peso.Text=("Tiene sobrepeso");
             end
        end

        % Value changed function: Lista56
        function Lista56ValueChanged(app, event)
           %% para la de barras   
           load Heart.mat
           Edad=heart_data.age;
           Edad=Edad/365;
           T=size(Edad,1);
           Hombresmen=0;
           Mujeresmen=0;
           Hombresmay =0;
           Mujeresmay=0;
           PC=heart_data.cardio;
           Gen=heart_data.gender;
           for i= 1:T %%Menores
           if Edad(i)>50 && PC(i)==1 && Gen(i) ==1
           Hombresmen=Hombresmen+1;
           elseif Edad(i)>50 && PC(i)==1 && Gen(i)==2
           Mujeresmen=Mujeresmen+1;
           end
           end
           for i= 1:T %%Mayores
              if Edad(i)<50 && PC(i)==1 && Gen(i) ==1 
                 Hombresmay=Hombresmay+1;
              elseif Edad(i)>50 && PC(i)==1 && Gen(i)==2
                 Mujeresmay=Mujeresmay+1;
              end
            end
            x=["Menores de 50 años", "Mayores de 50 años" ];
            y=[Hombresmen Mujeresmen ; Hombresmay Mujeresmay];
            %% para las otras 2 xd
            Col=heart_data.cholesterol;
            Gluc=heart_data.gluc;
            Altura=heart_data.height;
            Peso=heart_data.weight;
            Altura=Altura/100;
            Altura=Altura.*Altura;
            imc=Peso./Altura;
            Sis=heart_data.ap_hi;
            Dias=heart_data.ap_lo;
            Ap=Sis-Dias/3;
            Ap=Ap+Dias;
            %% todo esto es lo necesario para que se ejecute el codigo (globalizarlo)
            value = str2double(app.Lista56.Value);
            if value == 5
               app.t1.Text=("Hombres y mujeres con problemas cardiacos");
               app.t2.Text=("Hombres y mujeres con problemas cardiacos");
               app.x1.Text=("Rangos de edades");
               app.y1.Text=("Personas con PC");
               app.x2.Text=("Rangos de edades");
               app.y2.Text=("Personas con PC");
               app.info.Text=("El azul son hombres y " + ...
                              "el rojo son mujeres con PC");
               bar(app.Grafica1,x,y)  
                bar(app.Grafica2,x,y)     
            elseif value ==6
                gscatter (app.Grafica1,imc,Col)
                app.t1.Text=("Grafica de Imc vs Colesterol");
                app.x1.Text=("Indice de masa corporal");
                app.y1.Text=("Niveles de colesterol");
                gscatter (app.Grafica2,Gluc,Ap)
                app.t2.Text=("Grafica de Glucosa vs Presion arterial media");
                app.x1.Text=("Nivel de glucosa");
                app.y1.Text=("Presion arterial media");
                app.info.Text=("Los pandas son re lindos :D");
            end
            
        end

        % Value changed function: QuequieresobservarDropDown
        function QuequieresobservarDropDownValueChanged(app, event)
            load Heart.mat
            Edad=heart_data.age;
            Edad=Edad/365;
            T=size(Edad,1);
            Con=40954;
            Sin=29046;
            Col=heart_data.cholesterol;
            Sis=heart_data.ap_hi;
            Dias=heart_data.ap_lo;
            Ap=Sis-Dias/3;
            Ap=Ap+Dias;
            Imccon=0;
            PC=heart_data.cardio;
            Altura=heart_data.height;
            Peso=heart_data.weight;
            Altura=Altura/100;
            Altura=Altura.*Altura;
            imc=Peso./Altura;
            for i= 1:T 
                if PC(i)==1 
                   Imccon=Imccon+imc(i);
                else
                   Imccon;
                end
            end
            mediaImc=Imccon/Con;
            Imccuadrada=(imc-mediaImc);
            Imccuadrada=Imccuadrada.*Imccuadrada;
            Imcsuma=0;
            for i= 1:T 
    
                Imcsuma=Imcsuma+Imccuadrada(i);
      
            end
            Desestandar=Imcsuma/Con;

            Imcsin=0;
            for i= 1:T 
                if PC(i)==0 
                   Imcsin=Imcsin+imc(i);
                else
                   Imcsin;
                end
             end
             mediaImcsin=Imcsin/Sin;
             imccuadrada=(imc-mediaImcsin);
             imccuadrada=imccuadrada.*imccuadrada;
             imcsum=0;
             for i= 1:T 
                imcsum=imcsum+imccuadrada(i);
             end
             desestandarimc=imcsum/Sin;

             Pam=0;
             for i= 1:T 
                 if PC(i)==1 
                   Pam=Pam+Ap(i);
                  else
                   Pam;
                  end
             end
             Media=Pam/Con;
             cuadrada=(Ap-Media);
             cuadrada=cuadrada.*cuadrada;       
             Pamsum=0;
             for i= 1:T 
                Pamsum=Pamsum+cuadrada(i);
             end
             desestandar=Pamsum/Con;

             Pams=0;
             for i= 1:T 
                if PC(i)==0
                  Pams=Pams+Ap(i);
                else
                  Pams;
                end
              end
              Mediaps=Pams/Sin;
              cuadradaps=(Ap-Mediaps);
              cuadradaps=cuadradaps.*cuadradaps;
              Pamsums=0;
              for i= 1:T 
                Pamsums=Pamsums+cuadradaps(i);
               end
              desestandarps=Pamsums/Sin;
              Pamcol=0;
              for i= 1:T 
                  if PC(i)==1
                    Pamcol=Pamcol+Col(i);
                  else
                    Pamcol;
                  end
               end
               Mediacol=Pamcol/Con;
               cuadradacol=(Col-Mediacol);
               cuadradacol=cuadradacol.*cuadradacol;
               colsum=0;
               for i= 1:T 
                  colsum=colsum+cuadradacol(i);
               end
               desestandarcol=colsum/Con;
               Paco=0;
               for i= 1:T 
                  if PC(i)==0
                     Paco=Paco+Col(i);
                  else
                     Paco;
                  end
               end
               Mediaco=Paco/Sin;
               cuadradaco=(Col-Mediaco);
               cuadradaco=cuadradaco.*cuadradaco;
               cosum=0;
               for i= 1:T 
                  cosum=cosum+cuadradaco(i);
               end
              desestandarco=cosum/Sin;
            %% todo esto es lo necesario para que se ejecute el codigo (globalizarlo)

            value = str2double(app.QuequieresobservarDropDown.Value);
            
             if value == 1
                   app.MC.Text=("La media de IMC de personas con problemas cardiacos es de "+ mediaImc);
                   app.MS.Text=("La media de Imc de personas sin problemas cardiacos es de  "+mediaImcsin);
                   app.DEC.Text=("La desviacion estandar de IMC de personas con problemas cardiacos es de "+Desestandar);
                   app.DES.Text=("La desviacion estandar de IMC de personas sin problemas cardiacos es de "+desestandarimc);
            elseif value ==2
                app.MC.Text=("La media de PAM de personas con problemas cardiacos es de  "+Media);
                app.MS.Text=("La media de PAM de personas Sin problemas cardiacos es de  "+Mediaps);
                app.DEC.Text=("La desviacion estandar de PAM de personas con problemas cardiacos es de "+desestandar);
                app.DES.Text=("La desviacion estandar de PAM de personas sin problemas cardiacos es de "+desestandarps);
             else 
                app.MC.Text=("La media del nivel de colesterol de personas con problemas cardiacos es de  "+Mediacol);
                app.MS.Text=("La media del nivel de colesterol de personas sin problemas cardiacos es de  "+Mediaco);
                app.DEC.Text=("La desviacion estandar del nivel de colesterol de personas con problemas cardiacos es de "+desestandarcol);
                app.DES.Text=("La desviacion estandar del nivel de colesterol de personas sin problemas cardiacos es de "+desestandarco);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica1 = uiaxes(app.UIFigure);
            title(app.Grafica1, 'Grafica 1')
            app.Grafica2 = uiaxes(app.UIFigure);
            title(app.Grafica2, 'Grafica 2')
            app.QuequieresgraficarLabel = uilabel(app.UIFigure);
            app.QuequieresgraficarLabel.Text = 'Que quieres graficar?';
            app.Lista56 = uidropdown(app.UIFigure);
            app.Lista56.Items = {'.', '5', '6'};
            app.Lista56.ValueChangedFcn = createCallbackFcn(app, @Lista56ValueChanged, true);
            app.Lista56.Value = '.';
            app.Boton = uibutton(app.UIFigure, 'push');
            app.Boton.ButtonPushedFcn = createCallbackFcn(app, @BotonButtonPushed, true);
            app.Boton.Text = 'Mostrar los datos';
            app.EscribeelIDdelparticipanteEditFieldLabel = uilabel(app.UIFigure);
            app.EscribeelIDdelparticipanteEditFieldLabel.Text = 'Escribe el ID del participante';
            app.NUM = uieditfield(app.UIFigure, 'numeric');
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = ':D';
            app.Procar = uilabel(app.UIFigure);
            app.Procar.Text = '';
            app.Fum = uilabel(app.UIFigure);
            app.Fum.Text = '';
            app.Alco = uilabel(app.UIFigure);
            app.Alco.Text = '';
            app.Ejer = uilabel(app.UIFigure);
            app.Ejer.Text = '';
            app.Peso = uilabel(app.UIFigure);
            app.Peso.Text = '';
            app.PuntoLabel = uilabel(app.UIFigure);
            app.PuntoLabel.Text = 'Punto->';
            app.x1 = uilabel(app.UIFigure);
            app.x1.Text = '';
            app.y1 = uilabel(app.UIFigure);
            app.y1.Text = '';
            app.y2 = uilabel(app.UIFigure);
            app.y2.Text = '';
            app.x2 = uilabel(app.UIFigure);
            app.x2.Text = '';
            app.t1 = uilabel(app.UIFigure);
            app.t1.Text = '';
            app.t2 = uilabel(app.UIFigure);
            app.t2.Text = '';
            app.info = uilabel(app.UIFigure);
            app.info.Interpreter = 'tex';
            app.info.Text = '';
            app.QuequieresobservarDropDownLabel = uilabel(app.UIFigure);
            app.QuequieresobservarDropDownLabel.Text = 'Que quieres observar?';
            app.QuequieresobservarDropDown = uidropdown(app.UIFigure);
            app.QuequieresobservarDropDown.Items = {'.', '1', '2', '3'};
            app.QuequieresobservarDropDown.ValueChangedFcn = createCallbackFcn(app, @QuequieresobservarDropDownValueChanged, true);
            app.QuequieresobservarDropDown.Value = '.';
            app.Indicedemasacorporal2Presinarterial3mediaColesterolLabel = uilabel(app.UIFigure);
            app.Indicedemasacorporal2Presinarterial3mediaColesterolLabel.Text = {'1=Indice de masa corporal'; '2=Presión arterial '; '3=mediaColesterol'};
            app.MC = uilabel(app.UIFigure);
            app.MC.Text = '';
            app.MS = uilabel(app.UIFigure);
            app.MS.Text = '';
            app.DEC = uilabel(app.UIFigure);
            app.DEC.Text = '';
            app.DES = uilabel(app.UIFigure);
            app.DES.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pandas.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end